classdef logger_CCEP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadXLSXButton                matlab.ui.control.Button
        ConfirmButton                 matlab.ui.control.Button
        subjvarEditField              matlab.ui.control.EditField
        subjvarEditFieldLabel         matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        SUBJVARREFERENCELabel         matlab.ui.control.Label
        BiphasicValenceDropDown       matlab.ui.control.DropDown
        BiphasicValenceDropDownLabel  matlab.ui.control.Label
        ELECTRODESLabel               matlab.ui.control.Label
        DurationsecEditField          matlab.ui.control.NumericEditField
        DurationsecEditFieldLabel     matlab.ui.control.Label
        CurrentmAEditField            matlab.ui.control.NumericEditField
        CurrentmAEditFieldLabel       matlab.ui.control.Label
        PulseWidthmsEditField         matlab.ui.control.NumericEditField
        PulseWidthmsEditFieldLabel    matlab.ui.control.Label
        FrequencyHzEditField          matlab.ui.control.NumericEditField
        FrequencyHzEditFieldLabel     matlab.ui.control.Label
        PARAMETERSLabel               matlab.ui.control.Label
        Warning                       matlab.ui.control.Label
        ExportXLSXButton              matlab.ui.control.Button
        RemoveButton                  matlab.ui.control.Button
        filenameField                 matlab.ui.control.EditField
        EDFFileLabel                  matlab.ui.control.Label
        browseButton                  matlab.ui.control.Button
        ContactPairDropDown_2         matlab.ui.control.DropDown
        EnterButton                   matlab.ui.control.Button
        NotesEditField                matlab.ui.control.EditField
        NotesEditFieldLabel           matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        ContactPairDropDown           matlab.ui.control.DropDown
        ContactPairDropDownLabel      matlab.ui.control.Label
        ShaftDropDown                 matlab.ui.control.DropDown
        FS_LabelDropDownLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        elec % electrode value stored in sheet
        subjvar  % FS_labels derived from subjVar
        FS_label % FS Label Array
        contact1% intial contact in contact pair
        contact2% secondary contact in contact pair
        notes % notes entered by RA
        table % table object that will be exported as csv
        Shaft % shaft component of electrode value
        Contact  %contact component of electrode value
        EDF_num% EDF file number
        EDF_path %EDF file path
        subjvar_path % Path to subjvar.mat file
        EDF_path_print %shortened EDF path name
        subjname %subjname
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: ShaftDropDown
        function ShaftDropDownValueChanged(app, event)
            app.elec = app.ShaftDropDown.Value;
        end

        % Drop down opening function: ShaftDropDown
        function ShaftDropDownOpening(app, event)
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)

            app.elec =string(app.ShaftDropDown.Value)+string(app.ContactPairDropDown.Value)+'-'+string(app.ShaftDropDown.Value)+string(app.ContactPairDropDown_2.Value); %when the enter button is pushed update the electrode value to store the shaft and contact information
            newData = {app.elec,app.EDF_num,app.notes,app.EDF_path_print,app.FrequencyHzEditField.Value,app.PulseWidthmsEditField.Value,app.CurrentmAEditField.Value, app.DurationsecEditField.Value, app.BiphasicValenceDropDown.Value}; %create data for each column
            app.UITable.Data = [app.table;newData]; %update the table display with the latest value that was entered
            app.table =[app.table;newData]; %update the value with the latest value that was entered

        end

        % Value changed function: ContactPairDropDown
        function ContactPairDropDownValueChanged(app, event)
            app.contact1 = app.ContactPairDropDown.Value;
            suggested_pair = str2double(app.contact1)+1
            if suggested_pair<20
                app.ContactPairDropDown_2.Value = app.ContactPairDropDown_2.Items{suggested_pair}
            end
        end

        % Value changed function: NotesEditField
        function NotesEditFieldValueChanged(app, event)
            app.notes = app.NotesEditField.Value;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
      
            
        end

        % Value changed function: ContactPairDropDown_2
        function ContactPairDropDown_2ValueChanged(app, event)
            app.contact2 = app.ContactPairDropDown_2.Value;
            
        end

        % Button pushed function: browseButton
        function browseButtonPushed(app, event)
             [file,path,indx] = uigetfile('*.edf');   %locate an EDF file
            app.EDF_path = [path file];
            pat = regexpPattern('E(\d)*-(\d)*_');
            pat2 =  regexpPattern('E(\d)*-(\d)*_(\d)*.edf');
            app.EDF_num = extractBetween(app.EDF_path,pat, ".edf");  %extract the number value from the edf file name
            app.EDF_path_print = extract(app.EDF_path, pat2);
            app.filenameField.Value = app.EDF_path;  

            edfFile = dir(app.EDF_path);
            size = edfFile.bytes;
            if size<4000000   %Give warning if EDF file is too small
                 app.Warning.Text ="WARNING: This file is below 40Mb";  
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.table(height(app.table),:)=[];
            app.UITable.Data = app.table
        end

        % Button pushed function: ExportXLSXButton
        function ExportXLSXButtonPushed(app, event)
            name = strcat(app.subjname,'_CCEP.xlsx');
            writetable(app.table, name);
        end

        % Value changed function: FrequencyHzEditField
        function FrequencyHzEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
             [file,path,indx] = uigetfile('*.mat')
            app.subjvar_path = [path file]
            app.subjvarEditField.Value = app.subjvar_path
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.subjvar = load(app.subjvar_path);  %load in subjVar data
            app.subjname = app.subjvar.subjVar.sbj_name;
            app.FS_label = app.subjvar.subjVar.elinfo.FS_label;
            app.Shaft ={};
            app.Contact={};

            for n=1:length(app.FS_label)
                label = app.FS_label{n};
                pat = regexpPattern('\d+');
                shaft = extractBefore(label, pat);
                contact = extract(label,pat);
                app.Shaft =[app.Shaft;shaft];
                app.Contact =[app.Contact;contact];
                app.Shaft =unique(app.Shaft);
            end
                
            app.ShaftDropDown.Items = app.Shaft;
            app.ContactPairDropDown.Items = string({1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18,19,20});
            app.ContactPairDropDown_2.Items = string({1,2,3,4,5,6,7,8,9,10,11,12,13,14,15,16,17,18,19,20});
            app.table  = readtable("export_template.xlsx");
            app.UITable.Data = app.table;

        end

        % Button pushed function: LoadXLSXButton
        function LoadXLSXButtonPushed(app, event)
             [file,path,indx] = uigetfile('*.xlsx')
             app.table  = readtable([path file]);
              app.UITable.Data = app.table;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FS_LabelDropDownLabel = uilabel(app.UIFigure);
            app.FS_LabelDropDownLabel.Text = 'Shaft';
            app.ShaftDropDown = uidropdown(app.UIFigure);
            app.ShaftDropDown.Items = {'Option 1'};
            app.ShaftDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ShaftDropDownOpening, true);
            app.ShaftDropDown.ValueChangedFcn = createCallbackFcn(app, @ShaftDropDownValueChanged, true);
            app.ContactPairDropDownLabel = uilabel(app.UIFigure);
            app.ContactPairDropDownLabel.Text = 'Contact Pair';
            app.ContactPairDropDown = uidropdown(app.UIFigure);
            app.ContactPairDropDown.ValueChangedFcn = createCallbackFcn(app, @ContactPairDropDownValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ELEC'; 'EDF'; 'NOTES'; 'EDF_PATH'; 'FREQ(Hz)'; 'PULSE_WIDTH(ms)'; 'CURRENT(mA)'; 'DURATION(sec)'; 'BIPHASIC_VALENCE'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.NotesEditFieldLabel = uilabel(app.UIFigure);
            app.NotesEditFieldLabel.Text = 'Notes';
            app.NotesEditField = uieditfield(app.UIFigure, 'text');
            app.NotesEditField.ValueChangedFcn = createCallbackFcn(app, @NotesEditFieldValueChanged, true);
            app.NotesEditField.Value = 'N/A';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ContactPairDropDown_2 = uidropdown(app.UIFigure);
            app.ContactPairDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ContactPairDropDown_2ValueChanged, true);
            app.browseButton = uibutton(app.UIFigure, 'push');
            app.browseButton.ButtonPushedFcn = createCallbackFcn(app, @browseButtonPushed, true);
            app.browseButton.Text = 'browse';
            app.EDFFileLabel = uilabel(app.UIFigure);
            app.EDFFileLabel.Text = 'EDF File';
            app.filenameField = uieditfield(app.UIFigure, 'text');
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.ExportXLSXButton = uibutton(app.UIFigure, 'push');
            app.ExportXLSXButton.ButtonPushedFcn = createCallbackFcn(app, @ExportXLSXButtonPushed, true);
            app.ExportXLSXButton.Text = 'Export XLSX';
            app.Warning = uilabel(app.UIFigure);
            app.Warning.Text = '';
            app.PARAMETERSLabel = uilabel(app.UIFigure);
            app.PARAMETERSLabel.Text = 'PARAMETERS';
            app.FrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldValueChanged, true);
            app.FrequencyHzEditField.Value = 0.5;
            app.PulseWidthmsEditFieldLabel = uilabel(app.UIFigure);
            app.PulseWidthmsEditFieldLabel.Text = 'Pulse Width (ms)';
            app.PulseWidthmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulseWidthmsEditField.Value = 0.2;
            app.CurrentmAEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentmAEditFieldLabel.Text = 'Current (mA)';
            app.CurrentmAEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentmAEditField.Value = 6;
            app.DurationsecEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsecEditFieldLabel.Text = 'Duration (sec)';
            app.DurationsecEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsecEditField.Value = 90;
            app.ELECTRODESLabel = uilabel(app.UIFigure);
            app.ELECTRODESLabel.Text = 'ELECTRODES';
            app.BiphasicValenceDropDownLabel = uilabel(app.UIFigure);
            app.BiphasicValenceDropDownLabel.Text = 'Biphasic Valence';
            app.BiphasicValenceDropDown = uidropdown(app.UIFigure);
            app.BiphasicValenceDropDown.Items = {'pos-neg', 'neg-pos'};
            app.BiphasicValenceDropDown.Value = 'pos-neg';
            app.SUBJVARREFERENCELabel = uilabel(app.UIFigure);
            app.SUBJVARREFERENCELabel.Text = 'SUBJVAR REFERENCE';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.subjvarEditFieldLabel = uilabel(app.UIFigure);
            app.subjvarEditFieldLabel.Text = 'subjvar';
            app.subjvarEditField = uieditfield(app.UIFigure, 'text');
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.LoadXLSXButton = uibutton(app.UIFigure, 'push');
            app.LoadXLSXButton.ButtonPushedFcn = createCallbackFcn(app, @LoadXLSXButtonPushed, true);
            app.LoadXLSXButton.Text = 'Load XLSX';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logger_CCEP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end